classdef trajectoryPlanning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        AiutoMenu                 matlab.ui.container.Menu
        InfoMenu                  matlab.ui.container.Menu
        EsciMenu                  matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        panelPositions            matlab.ui.container.Panel
        labelAvanzamento          matlab.ui.control.Label
        positionGraph             matlab.ui.control.Button
        positionTrajectory        matlab.ui.control.Button
        buttonLoadTimes           matlab.ui.control.Button
        textFieldTimes            matlab.ui.control.EditField
        TempiLabel                matlab.ui.control.Label
        textFieldPositions        matlab.ui.control.EditField
        PosizioniLabel            matlab.ui.control.Label
        buttonLoadPositions       matlab.ui.control.Button
        panelDataMatrix           matlab.ui.container.Panel
        matrixTable               matlab.ui.control.Table
        panelMatrix               matlab.ui.container.Panel
        rotationGraphButton       matlab.ui.control.Button
        rotationTrajectoryButton  matlab.ui.control.Button
        labelLoadMatrix           matlab.ui.control.Label
        buttonLoadMatrix          matlab.ui.control.Button
        textFieldMatrix           matlab.ui.control.EditField
        PosizioniLabel_2          matlab.ui.control.Label
        panelDataPositions        matlab.ui.container.Panel
        tableTimes                matlab.ui.control.Table
        tablePositions            matlab.ui.control.Table
    end

    
    properties (Access = public)
        positions = [];
        times = [];
        rotations = [];
        step = 0.1;
        pe = [];
        re = [];
        theta_vector = [];
        theta_plot = [];
        help = "L'applicativo permette la pianificazione di traiettorie su percorso assegnato " + ...
            "nello spazio operativo. L'utente dovrà fornire in ingresso posizione ed " + ...
            "orientamento dell'organo terminale nei punti di percorso e sequenza " + ...
            "dei tempi per ciascun tratto. Per l'orientamento è necessario utilizzare " + ...
            "Matrici di rotazione. La primitiva di percorso " + ...
            "utilizzata è il segmento. Si ringrazia il prof. " + ...
            "Caccavale per gli insegnamenti ricevuti.";
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: buttonLoadPositions
        function loadPositions(app, event)
            [filename,path]=uigetfile();
            if filename == 0
                figure(app.UIFigure);
                return;
            end
            app.textFieldPositions.Value=filename;
            figure(app.UIFigure);
            load([path filename], '-mat');
            %CONTROLLO SUL FILE GIUSTO
            if(~contains(filename, 'positions'))
                f = errordlg("Inserisci il file positions.mat!","Errore",'modal');
                app.textFieldPositions.Value = '';
                return;
            end
            %CONTROLLO SUI TEMPI
            if ~isempty(app.textFieldTimes.Value)
                if size(A,1) ~= size(app.times,2)
                    f = errordlg("Le dimensioni tra tempi e posizioni non coincidono","Errore",'modal');
                    app.textFieldPositions.Value = '';
                    return;
                end
            end
            %CONTROLLO SULLE ROTAZIONI
            if ~isempty(app.textFieldMatrix.Value)
                if size(A,1)*3 ~= size(app.rotations,1)
                    f = errordlg("Le dimensioni tra posizioni e matrici di rotazione non coincidono","Errore",'modal');
                    app.textFieldPositions.Value = '';
                    return;
                end
            end
            app.positions = A;
            app.tablePositions.Data = app.positions;
            app.tablePositions.RowName = 'numbered';
            if(~isempty(app.textFieldPositions.Value) && ~isempty(app.textFieldTimes.Value))
                app.positionTrajectory.Enable = "on";
            end
        end

        % Button pushed function: buttonLoadTimes
        function loadTimes(app, event)
            [filename,path]=uigetfile();
            if filename == 0
                figure(app.UIFigure);
                return;
            end
            app.textFieldTimes.Value=filename;
            figure(app.UIFigure);
            load([path filename], '-mat');
            %CONTROLLO SUL FILE GIUSTO
            if(~contains(filename, 'times'))
                f = errordlg("Inserisci il file times.mat!","Errore",'modal');
                app.textFieldTimes.Value = '';
                return;
            end
            %CONTROLLO SULLE POSIZIONI
            if ~isempty(app.textFieldPositions.Value)
                if size(t,2) ~= size(app.positions,1)-1
                    f = errordlg("Le dimensioni tra tempi e posizioni non coincidono","Errore",'modal');
                    app.textFieldTimes.Value = '';
                    return;
                end
            end
            %CONTROLLO SULLE ROTAZIONI
            if ~isempty(app.textFieldMatrix.Value)
                if (size(t,2)+1)*3 ~= size(app.rotations,1)
                    f = errordlg("Le dimensioni tra tempi e matrici di rotazione non coincidono","Errore",'modal');
                    app.textFieldTimes.Value = '';
                    return;
                end
            end
            app.times(1) = 0;
            for i=2:(size(t,2)+1)
                app.times = [app.times, (app.times(i-1)+t(i-1))];
            end
            app.tableTimes.Data = t;
            c = {};
            for i=1:size(t,2)
                c(i) = cellstr(strcat("Tratto ", string(i)));
            end
            app.tableTimes.ColumnName = c;
            if(~isempty(app.textFieldPositions.Value) && ~isempty(app.textFieldTimes.Value))
                app.positionTrajectory.Enable = "on";
            end
            assignin('base','t', t);
        end

        % Button pushed function: positionTrajectory
        function positionsPlanning(app, event)
            app.pe = trajectory_planning_position(app.positions, app.times, app.step);
            app.labelAvanzamento.Text = 'Traiettoria di Posizione calcolata! Ora puoi visualizzare il grafico';
            app.positionGraph.Enable = "on";
            if~isempty(app.textFieldMatrix.Value)
                app.rotationTrajectoryButton.Enable = "on";
            end
            assignin('base','pe', app.pe);
        end

        % Button pushed function: positionGraph
        function generatePositionGraph(app, event)
            fig1 = figure('Name', 'Traiettoria di Posizione');
            grid on;
            title('Traiettoria di Posizione');
            xlabel('Asse X');
            ylabel('Asse Y');
            zlabel('Asse Z');
            hold on;
            set(gca, 'XLim', [0 6], 'YLim', [0 6], 'ZLim', [0 6]);
            curve = animatedline('Color', 'b');
            for i=1:size(app.pe,1)
                view(3);
                addpoints(curve, app.pe(i,1), app.pe(i,2), app.pe(i,3));
                drawnow;
            end
            for i=1:size(app.positions,1)
                scatter3(app.positions(i,1),app.positions(i,2),app.positions(i,3), 'red', 'filled');
            end
            hold off;
            legend('Traiettoria', 'Punti Inseriti','Location','NorthWest');
            app.labelAvanzamento.Text = 'Grafico di Posizione generato!';
        end

        % Button pushed function: buttonLoadMatrix
        function loadMatrix(app, event)
            [filename,path]=uigetfile();
            if filename == 0
                figure(app.UIFigure);
                return;
            end
            app.textFieldMatrix.Value=filename;
            figure(app.UIFigure);
            load([path filename], '-mat');
            %CONTROLLO SUL FILE GIUSTO
            if(~contains(filename, 'rotations'))
                f = errordlg("Inserisci il file rotations.mat!","Errore",'modal');
                app.textFieldMatrix.Value = '';
                return;                
            end
            if(mod(size(B,1), 3) ~= 0)
                f = errordlg("Le righe nel file delle matrici di rotazioni devono essere in numero multiplo di 3!","Errore",'modal');
                app.textFieldMatrix.Value = '';
                return;
            end
            %CONTROLLO SULLE POSIZIONI
            if ~isempty(app.textFieldPositions.Value)
                if size(B,1) ~= size(app.positions,1)*3
                    f = errordlg("Le dimensioni tra posizioni e matrici di rotazione non coincidono","Errore",'modal');
                    app.textFieldMatrix.Value = '';
                    return;
                end
            end
            %CONTROLLO SUI TEMPI
            if ~isempty(app.textFieldTimes.Value)
                if size(B,1) ~= size(app.times,2)*3
                    f = errordlg("Le dimensioni tra tempi e matrici di rotazione non coincidono","Errore",'modal');
                    app.textFieldMatrix.Value = '';
                    return;
                end
            end
            %CONTROLLO VALIDITA'
            count = 1;
            for i=1:3:size(B,1)-2
                R = B(i:i+2, :);
                if ~isequal(uint8(R'*R),eye(3))
                    f = errordlg(strcat('La matrice di rotazione numero ', string(count), ' non è valida'),"Errore",'modal');
                    app.textFieldMatrix.Value = '';
                    return;
                end
                count = count + 1;
            end
            app.rotations = B;
            app.matrixTable.Data = B;
            r = {};
            count = 1;
            for i=3:3:size(app.rotations,1)
                r(i-2) = cellstr(string(count));
                r(i-1) = cellstr(' ');
                r(i) = cellstr(' ');
                count = count + 1;
            end
            app.matrixTable.RowName = r;
            if~isempty(app.textFieldMatrix.Value) && (size(app.pe,1) ~= 0)
                app.rotationTrajectoryButton.Enable = "on";
            end
        end

        % Button pushed function: rotationTrajectoryButton
        function generateRotationTrajectory(app, event)
            [app.theta_vector, app.re, app.theta_plot] = trajectory_planning_rotation(app.rotations, app.times, app.step);
            app.labelLoadMatrix.Text = 'Traiettoria di Rotazione Generata! Ora puoi visualizzare il grafico';
            app.rotationGraphButton.Enable = "on";
            assignin('base','re', app.re);
            assignin('base', 'theta', app.theta_vector)
        end

        % Button pushed function: rotationGraphButton
        function generateRotationGraph(app, event)
            A = app.positions;
            fig2 = figure('Name', 'Traiettoria di Rotazione');
            title('Traiettoria di Rotazione');
            set(gca, 'XLim', [0 6], 'YLim', [0 6], 'ZLim', [0 6]);
            xlabel('Asse X');
            ylabel('Asse Y');
            zlabel('Asse Z');
            view(3)
            grid on
            hold on
            for i=1:size(A,1)
                scatter3(A(i,1),A(i,2),A(i,3), 'red', 'filled');
                text(A(i,1),A(i,2),A(i,3), ['Angolo ', num2str(i), ': ', num2str(app.theta_plot(i))], ...
                    'HorizontalAlignment', 'left', ...
                    'VerticalAlignment', 'top');
            end
            for i=1:size(app.re,1)
                q = quiver3(app.pe(i,1), app.pe(i,2), app.pe(i,3), app.re(i,1),app.re(i,2),app.re(i,3),'Color','#80bfff');
                set(q,'AutoScale','on', 'AutoScaleFactor', 2)
            end
            legend('Punti Inseriti','Location','NorthWest');
            app.labelLoadMatrix.Text = 'Grafico di Rotazione generato!';
        end

        % Menu selected function: AiutoMenu
        function AiutoMenuSelected(app, event)
            msgbox(app.help, 'Aiuto');
        end

        % Callback function: EsciMenu, UIFigure
        function EsciMenuSelected(app, event)
            answer = questdlg('Vuoi veramente uscire?', 'Uscita', 'Si', 'No', 'Si');
            switch answer
                case 'Si'
                    closereq();
                    close all;
                case 'No'
                    figure(app.UIFigure);
            end
        end

        % Menu selected function: InfoMenu
        function InfoMenuSelected(app, event)
            msgbox("App sviluppata da Nicolas Calzone matr. 65121 e Giuseppe Ciro Riccardi matr.63956", 'Info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pianificazione della Traiettoria nello Spazio Operativo';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @EsciMenuSelected, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.AiutoMenu = uimenu(app.FileMenu);
            app.AiutoMenu.MenuSelectedFcn = createCallbackFcn(app, @AiutoMenuSelected, true);
            app.AiutoMenu.Text = 'Aiuto';
            app.InfoMenu = uimenu(app.FileMenu);
            app.InfoMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoMenuSelected, true);
            app.InfoMenu.Text = 'Info';
            app.EsciMenu = uimenu(app.FileMenu);
            app.EsciMenu.MenuSelectedFcn = createCallbackFcn(app, @EsciMenuSelected, true);
            app.EsciMenu.Text = 'Esci';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.panelDataPositions = uipanel(app.GridLayout);
            app.panelDataPositions.Title = 'Dati di Posizione e Tempi';
            app.tablePositions = uitable(app.panelDataPositions);
            app.tablePositions.ColumnName = {'x'; 'y'; 'z'};
            app.tablePositions.RowName = {};
            app.tableTimes = uitable(app.panelDataPositions);
            app.tableTimes.ColumnName = '';
            app.tableTimes.RowName = {};
            app.panelMatrix = uipanel(app.GridLayout);
            app.panelMatrix.Title = 'Matrici di Rotazione';
            app.PosizioniLabel_2 = uilabel(app.panelMatrix);
            app.PosizioniLabel_2.Text = 'Matrici di Rotazione';
            app.textFieldMatrix = uieditfield(app.panelMatrix, 'text');
            app.textFieldMatrix.Editable = 'off';
            app.buttonLoadMatrix = uibutton(app.panelMatrix, 'push');
            app.buttonLoadMatrix.ButtonPushedFcn = createCallbackFcn(app, @loadMatrix, true);
            app.buttonLoadMatrix.Text = 'Carica';
            app.labelLoadMatrix = uilabel(app.panelMatrix);
            app.labelLoadMatrix.Text = 'Carica i dati per generare la traiettoria di rotazione.';
            app.rotationTrajectoryButton = uibutton(app.panelMatrix, 'push');
            app.rotationTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @generateRotationTrajectory, true);
            app.rotationTrajectoryButton.Enable = 'off';
            app.rotationTrajectoryButton.Text = 'Genera Traiettoria di Rotazione';
            app.rotationGraphButton = uibutton(app.panelMatrix, 'push');
            app.rotationGraphButton.ButtonPushedFcn = createCallbackFcn(app, @generateRotationGraph, true);
            app.rotationGraphButton.Enable = 'off';
            app.rotationGraphButton.Text = 'Genera Grafico';
            app.panelDataMatrix = uipanel(app.GridLayout);
            app.panelDataMatrix.Title = 'Dati delle Matrici';
            app.matrixTable = uitable(app.panelDataMatrix);
            app.matrixTable.ColumnName = {'x'; 'y'; 'z'};
            app.matrixTable.RowName = {};
            app.panelPositions = uipanel(app.GridLayout);
            app.panelPositions.Title = 'Posizioni e Tempi';
            app.buttonLoadPositions = uibutton(app.panelPositions, 'push');
            app.buttonLoadPositions.ButtonPushedFcn = createCallbackFcn(app, @loadPositions, true);
            app.buttonLoadPositions.Text = 'Carica';
            app.PosizioniLabel = uilabel(app.panelPositions);
            app.PosizioniLabel.Text = 'Posizioni';
            app.textFieldPositions = uieditfield(app.panelPositions, 'text');
            app.textFieldPositions.Editable = 'off';
            app.TempiLabel = uilabel(app.panelPositions);
            app.TempiLabel.Text = 'Tempi';
            app.textFieldTimes = uieditfield(app.panelPositions, 'text');
            app.textFieldTimes.Editable = 'off';
            app.buttonLoadTimes = uibutton(app.panelPositions, 'push');
            app.buttonLoadTimes.ButtonPushedFcn = createCallbackFcn(app, @loadTimes, true);
            app.buttonLoadTimes.Text = 'Carica';
            app.positionTrajectory = uibutton(app.panelPositions, 'push');
            app.positionTrajectory.ButtonPushedFcn = createCallbackFcn(app, @positionsPlanning, true);
            app.positionTrajectory.Enable = 'off';
            app.positionTrajectory.Text = 'Genera Traiettoria di Posizione';
            app.positionGraph = uibutton(app.panelPositions, 'push');
            app.positionGraph.ButtonPushedFcn = createCallbackFcn(app, @generatePositionGraph, true);
            app.positionGraph.Enable = 'off';
            app.positionGraph.Text = 'Genera Grafico';
            app.labelAvanzamento = uilabel(app.panelPositions);
            app.labelAvanzamento.Text = 'Carica i dati per generare la traiettoria di posizione.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trajectoryPlanning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end